classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        ImportImageMenu  matlab.ui.container.Menu
        BalanceButton    matlab.ui.control.Button
        UIImage          matlab.ui.control.Image
        UIAxesHistogram  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage   % 原始图像
        ProcessedImage  % 均衡化后的图像
    end
    
    methods (Access = private)
        
        function updateHistogram(app, img)
            % 如果是彩色图像，将其转换为灰度图像
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
            
            % 计算灰度直方图
            % 创建一个包含256个元素的零向量，用于存储每个灰度值的出现次数
            [rows, cols] = size(img);
            histogramData = zeros(1, 256);  % 0-255的灰度值
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j) + 1;  % +1 是因为灰度值从 0 开始
                    histogramData(grayValue) = histogramData(grayValue) + 1;
                end
            end
            
            % 清除之前的直方图
            cla(app.UIAxesHistogram);  % 清除 Axes 上之前的内容
            
            % 手动绘制直方图
            bar(app.UIAxesHistogram, 0:255, histogramData, 'FaceColor', 'b');
            title(app.UIAxesHistogram, '灰度直方图');
            xlabel(app.UIAxesHistogram, '灰度值');
            ylabel(app.UIAxesHistogram, '像素数量');
            axis(app.UIAxesHistogram, 'tight');  % 紧缩坐标轴
        end


        function balancedImg = customHistogramEqualization(app, img)
            % 如果是彩色图像，将其转换为灰度图像
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
            
            % 计算图像的直方图
            [rows, cols] = size(img);
            histogramData = zeros(1, 256);  % 0-255的灰度值
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j) + 1;  % +1 是因为灰度值从 0 开始
                    histogramData(grayValue) = histogramData(grayValue) + 1;
                end
            end
            
            % 计算累积分布函数（CDF）
            cdf = cumsum(histogramData) / (rows * cols);  % 归一化到 [0,1]
            
            % 创建新的均衡化图像
            balancedImg = uint8(zeros(rows, cols));  % 初始化均衡化后的图像
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j);  % 获取原图像的灰度值
                    % 映射到新的均衡化灰度值
                    balancedImg(i,j) = uint8(cdf(grayValue + 1) * 255);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportImageMenu, Menu
        function ImportImageMenuSelected(app, event)
            % 打开文件选择对话框
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp;*.gif', 'Image Files (*.jpg, *.png, *.jpeg, *.bmp, *.gif)'});
            
            if file ~= 0  % 如果用户选择了文件
                imgPath = fullfile(path, file);
                img = imread(imgPath);
                % 将图像路径赋给 UIImage 组件的 ImageSource 属性
                app.UIImage.ImageSource = imgPath;  % 显示图片
                
                if size(img, 3) == 3
                    img = rgb2gray(img);
                end
                app.OriginalImage = img;
                % 使用自定义函数更新灰度直方图
                updateHistogram(app, img);  % 调用自定义函数显示灰度直方图
            else
                disp('No image selected');
            end
        end

        % Button pushed function: BalanceButton
        function BalanceButtonPushed(app, event)
            if isempty(app.OriginalImage)
                % 如果没有导入图像，提示用户
                uialert(app.UIFigure, '请先导入图像！', '图像未加载');
                return;
            end
            
            % 对图像进行均衡化
            balancedImg = histeq(app.OriginalImage);
            app.ProcessedImage = balancedImg;
            
            if size(balancedImg, 3) == 1  % 如果是灰度图像
                balancedImgRGB = cat(3, balancedImg, balancedImg, balancedImg);  % 将灰度图像转换为 RGB 图像
            else
                balancedImgRGB = balancedImg;  % 如果已经是彩色图像，则不需要转换
            end
            
            
            % 更新 UIImage 组件中的图像
            app.UIImage.ImageSource = balancedImgRGB;  % 显示均衡化后的图像（RGB 格式）
            
            % 使用自定义函数更新灰度直方图
            updateHistogram(app, balancedImg);  % 调用自定义函数显示均衡化后的灰度直方图
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.Menu.Text = 'Menu';
            app.ImportImageMenu = uimenu(app.Menu);
            app.ImportImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.ImportImageMenu.Text = 'Import Image';
            app.UIAxesHistogram = uiaxes(app.UIFigure);
            title(app.UIAxesHistogram, '灰度直方图')
            xlabel(app.UIAxesHistogram, '灰度值')
            ylabel(app.UIAxesHistogram, '像素数量')
            zlabel(app.UIAxesHistogram, 'Z')
            app.UIImage = uiimage(app.UIFigure);
            app.BalanceButton = uibutton(app.UIFigure, 'push');
            app.BalanceButton.ButtonPushedFcn = createCallbackFcn(app, @BalanceButtonPushed, true);
            app.BalanceButton.Text = '均衡化';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end